classdef MasterProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea_2                    matlab.ui.control.TextArea
        TextArea                      matlab.ui.control.TextArea
        ChoppingPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        EndSliderLabel_3              matlab.ui.control.Label
        StartSlider                   matlab.ui.control.Slider
        EndSlider                     matlab.ui.control.Slider
        EndSliderLabel_2              matlab.ui.control.Label
        EffectsandModulationPanel     matlab.ui.container.Panel
        PlaybackSpeedSlider           matlab.ui.control.Slider
        PlaybackSpeedSliderLabel      matlab.ui.control.Label
        VolumeKnob                    matlab.ui.control.Knob
        VolumeKnobLabel               matlab.ui.control.Label
        DelayKnob                     matlab.ui.control.Knob
        DelayKnobLabel                matlab.ui.control.Label
        FeedbackKnob                  matlab.ui.control.Knob
        FeedbackKnobLabel             matlab.ui.control.Label
        EqualizerLabel                matlab.ui.control.Label
        kHzLabel_3                    matlab.ui.control.Label
        HzLabel_5                     matlab.ui.control.Label
        HzLabel_4                     matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        Equalizer_1000Hz              matlab.ui.control.Slider
        Equalizer_800Hz               matlab.ui.control.Slider
        Equalizer_500Hz               matlab.ui.control.Slider
        Equalizer_250Hz               matlab.ui.control.Slider
        Equalizer_150Hz               matlab.ui.control.Slider
        Equalizer_60Hz                matlab.ui.control.Slider
        PlaybackDirectionSwitch       matlab.ui.control.ToggleSwitch
        TabGroup                      matlab.ui.container.TabGroup
        InstructionsTab               matlab.ui.container.Tab
        EditField                     matlab.ui.control.EditField
        TextArea_3                    matlab.ui.control.TextArea
        TonesTab                      matlab.ui.container.Tab
        ExporttoSelectedSampleButton  matlab.ui.control.Button
        ToneGenerationPanel           matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        PlayButton_2                  matlab.ui.control.StateButton
        ToneHzSpinner                 matlab.ui.control.Spinner
        ToneHzSpinnerLabel            matlab.ui.control.Label
        WaveTypeDropDown              matlab.ui.control.DropDown
        UIAxes2                       matlab.ui.control.UIAxes
        BeatTab                       matlab.ui.container.Tab
        BeatPanel                     matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        BPMSpinner                    matlab.ui.control.Spinner
        BPMSpinnerLabel               matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Button_8                      matlab.ui.control.StateButton
        Button_7                      matlab.ui.control.StateButton
        Button_6                      matlab.ui.control.StateButton
        Button_5                      matlab.ui.control.StateButton
        Button_4                      matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        Button_2                      matlab.ui.control.StateButton
        PlayButton_3                  matlab.ui.control.Button
        Drum1Label                    matlab.ui.control.Label
        Button                        matlab.ui.control.StateButton
        LoadandPlaySamplesPanel       matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        Lamp_2                        matlab.ui.control.Lamp
        ONOFFLabel                    matlab.ui.control.Label
        Sample9Button                 matlab.ui.control.StateButton
        Sample8Button                 matlab.ui.control.StateButton
        Sample7Button                 matlab.ui.control.StateButton
        Sample6Button                 matlab.ui.control.StateButton
        Sample5Button                 matlab.ui.control.StateButton
        Sample4Button                 matlab.ui.control.StateButton
        Sample3Button                 matlab.ui.control.StateButton
        Sample2Button                 matlab.ui.control.StateButton
        Sample1Button                 matlab.ui.control.StateButton
        StopButton                    matlab.ui.control.Button
        RecordButton                  matlab.ui.control.StateButton
        PlayButton                    matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audiosamples; %array containing sample objects
        selected; % indicates which sample is in focus
        toneY; % stores array of generated tone samples
        toneFs; %sample rate of generated tone
        switchFlipped;
        recordobj;
        buttonnames;
        reverseFlipped;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.audiosamples = Sam.empty(0, 9);
            for i = 1:9
                app.audiosamples(i) = Sam();
            end
            app.recordobj = audiorecorder;
            app.Lamp_2.Enable = 'off';
            app.buttonnames = [app.Sample1Button, app.Sample2Button, app.Sample3Button, app.Sample4Button, app.Sample5Button, app.Sample6Button, app.Sample7Button, app.Sample8Button, app.Sample9Button];
            app.selected=0;
        end

        % Value changed function: Sample1Button
        function Sample1ButtonValueChanged(app, event)
            value = app.Sample1Button.Value;
            if (value)
                for i = 1:9
                    if i == 1
                        app.selected = 1;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            app.audiosamples(app.selected) = app.audiosamples(app.selected).Load();             
            app.buttonnames(app.selected).Text = app.audiosamples(app.selected).fileName;    
            %app.Sample1Button.Text = app.audiosamples.fileName;
            
            FinishSec = app.audiosamples(app.selected).finishSec;
            app.StartSlider.Limits(2) = FinishSec;
            app.EndSlider.Limits(2) = FinishSec;
            end
        end

        % Value changed function: Sample2Button
        function Sample2ButtonValueChanged(app, event)
            value = app.Sample2Button.Value;
            if (value)
                for i = 1:9
                    if i == 2
                        app.selected = 2;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample3Button
        function Sample3ButtonValueChanged(app, event)
            value = app.Sample3Button.Value;
             if (value)
                for i = 1:9
                    if i == 3
                        app.selected = 3;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample4Button
        function Sample4ButtonValueChanged(app, event)
            value = app.Sample4Button.Value;
             if (value)
                for i = 1:9
                    if i == 4
                        app.selected = 4;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample5Button
        function Sample5ButtonValueChanged(app, event)
            value = app.Sample5Button.Value;
             if (value)
                for i = 1:9
                    if i == 5
                        app.selected = 5;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample6Button
        function Sample6ButtonValueChanged(app, event)
            value = app.Sample6Button.Value;
             if (value)
                for i = 1:9
                    if i == 6
                        app.selected = 6;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample7Button
        function Sample7ButtonValueChanged(app, event)
            value = app.Sample7Button.Value;
             if (value)
                for i = 1:9
                    if i == 7
                        app.selected = 7;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample8Button
        function Sample8ButtonValueChanged(app, event)
            value = app.Sample8Button.Value;
             if (value)
                for i = 1:9
                    if i == 8
                        app.selected = 8;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: Sample9Button
        function Sample9ButtonValueChanged(app, event)
            value = app.Sample9Button.Value;
             if (value)
                for i = 1:9
                    if i == 9
                        app.selected = 9;
                    else
                        app.buttonnames(i).Value = 0;
                    end
                end
            else
                app.selected = 0;
            end
        end

        % Value changed function: PlayButton_2
        function PlayButton_2ValueChanged(app, event)
            value = app.PlayButton_2.Value;
            
            if strcmp(app.WaveTypeDropDown.Value, 'Sine') 
                [app.toneY, app.toneFs] = SinePitch(app.ToneHzSpinner.Value, 3, app.UIAxes2);

            elseif strcmp(app.WaveTypeDropDown.Value, 'Square Wave') 
                [app.toneY, app.toneFs] = SquarePitch(app.ToneHzSpinner.Value, 3, app.UIAxes2);
            elseif strcmp(app.WaveTypeDropDown.Value, 'SawTooth') 
                [app.toneY, app.toneFs] = SawPitch(app.ToneHzSpinner.Value, 3, app.UIAxes2);
            end
        end

        % Button pushed function: ExporttoSelectedSampleButton
        function ExporttoSelectedSampleButtonPushed(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            app.audiosamples(app.selected).y = app.toneY;
            app.audiosamples(app.selected).sampleRate = app.toneFs;
            app.audiosamples(app.selected).fileName = 'tone.wav';
            app.buttonnames(app.selected).Text = app.audiosamples(app.selected).fileName
            app.audiosamples(app.selected).start = 1;
            app.audiosamples(app.selected).finish = length(app.audiosamples(app.selected).y);
            app.audiosamples(app.selected).finishSec = (app.audiosamples(app.selected).finish-1)/app.audiosamples(app.selected).sampleRate;
            
            app.StartSlider.Limits(2) = app.audiosamples(app.selected).finishSec;
            app.EndSlider.Limits(2) = app.audiosamples(app.selected).finishSec;
            end
        end

        % Value changed function: StartSlider
        function StartSliderValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            value = app.StartSlider.Value;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).Chop(value, app.EndSlider.Value)
            end
        end

        % Value changed function: EndSlider
        function EndSliderValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            value = app.EndSlider.Value;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).Chop(app.StartSlider.Value, value)
            end
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            % FUNCTION TO CONTROL THE VOLUME LEVEL - modified 6/6/2023 @
            % 6:06 PM
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            volumeKnob = app.VolumeKnob.Value; % VALUE OF KNOB
            app.audiosamples(app.selected) = app.audiosamples(app.selected).VolumeKnobChanged(volumeKnob);
            end
            
        end

        % Value changed function: PlaybackDirectionSwitch
        function PlaybackDirectionSwitchValueChanged(app, event)
            % FUNCTION TO REVERSE THE AUDIO - last modified 6/6/23 @ 6:05
            % PM
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
                switchFlipped = app.PlaybackDirectionSwitch.Value;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).Direction(switchFlipped);
            end
        end

        % Value changed function: PlaybackSpeedSlider
        function PlaybackSpeedSliderValueChanged(app, event)
            % FUNCTION TO CONTROL PLAYBACK SPEED - last modified 6/6/23 @
            % 6:05 PM
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            playbackSpeed = app.PlaybackSpeedSlider.Value;

            app.audiosamples(app.selected) = app.audiosamples(app.selected).Playbackspeed(playbackSpeed)
            end
        end

        % Value changed function: FeedbackKnob
        function FeedbackKnobValueChanged(app, event)
            % FUNCTION TO CONTROL THE FEEDBACK - added 6/1/2023 ~1 AM

            % sampleAudio = inputted audio file; sampleRate = audio sample
            %sampleAudio = app.audiosamples(app.selected);
            %sampleRate = app.audiosamples(app.selected).sampleRate;
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            feedbackKnob = app.FeedbackKnob.Value; % VALUE OF KNOB
            app.audiosamples(app.selected) = app.audiosamples(app.selected).FeedbackKnobValueChanged(feedbackKnob);

            % app.audiosamples(app.selected) = app.audiosamples(app.selected).VolumeKnobChanged(volumeKnob)
            end
        end

        % Value changed function: DelayKnob
        function DelayKnobValueChanged(app, event)
            % FUNCTION TO DELAY THE AUDIO - added 6/1/2023 ~1 AM
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            delaySeconds = app.DelayKnob.Value; % VALUE OF KNOB
            
            app.audiosamples(app.selected) = app.audiosamples(app.selected).DelayKnobValueChanged(delaySeconds);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            app.audiosamples(app.selected) = app.audiosamples(app.selected).Play(app.UIAxes3);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.audiosamples(app.selected) = app.audiosamples(app.selected).Stop();
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            value = app.Button.Value;
            if value == 1
                app.Button.BackgroundColor = [0, 0, 0];
            else
                app.Button.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            value = app.Button_2.Value;
            if value == 1
                app.Button_2.BackgroundColor = [0, 0, 0];
            else
                app.Button_2.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            value = app.Button_3.Value;
            if value == 1
                app.Button_3.BackgroundColor = [0, 0, 0];
            else
                app.Button_3.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;
            value = app.Button_4.Value;
            if value == 1
                app.Button_4.BackgroundColor = [0, 0, 0];
            else
                app.Button_4.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            value = app.Button_5.Value;
            value = app.Button_5.Value;
            if value == 1
                app.Button_5.BackgroundColor = [0, 0, 0];
            else
                app.Button_5.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Value;
            value = app.Button_6.Value;
            if value == 1
                app.Button_6.BackgroundColor = [0, 0, 0];
            else
                app.Button_6.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            value = app.Button_7.Value;
            value = app.Button_7.Value;
            if value == 1
                app.Button_7.BackgroundColor = [0, 0, 0];
            else
                app.Button_7.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            value = app.Button_8.Value;
            value = app.Button_8.Value;
            if value == 1
                app.Button_8.BackgroundColor = [0, 0, 0];
            else
                app.Button_8.BackgroundColor = [1, 1, 1];
            end
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            beatPattern = [app.Button.Value,app.Button_2.Value, app.Button_3.Value, app.Button_4.Value, app.Button_5.Value, app.Button_6.Value, app.Button_7.Value, app.Button_8.Value];
            bpm = app.BPMSpinner.Value;
            Drum(bpm, beatPattern);
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            value = app.RecordButton.Value;
            if (value)
                record(app.recordobj);
                app.Lamp_2.Enable = 'on';
            else
                stop(app.recordobj);
                app.Lamp_2.Enable = 'off';
                app.audiosamples(app.selected).fileName = "User_Recording.wav";
                app.buttonnames(app.selected).Text = "User Recording";
                app.audiosamples(app.selected).y = getaudiodata(app.recordobj);
                app.audiosamples(app.selected).sampleRate = app.recordobj.SampleRate;
                app.audiosamples(app.selected).start = 1;
                app.audiosamples(app.selected).finish = length(app.audiosamples(app.selected).y);
                app.audiosamples(app.selected).finishSec = (app.audiosamples(app.selected).finish-1)/app.audiosamples(app.selected).sampleRate;
                
                app.StartSlider.Limits(2) = app.audiosamples(app.selected).finishSec;
                app.EndSlider.Limits(2) = app.audiosamples(app.selected).finishSec;
            end
            end


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            app.audiosamples(app.selected).Export();
            end
        end

        % Value changed function: Equalizer_60Hz
        function Equalizer_60HzValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            valueat60Hz = app.Equalizer_60Hz.Value;
            hertzTemp = 60;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).UniversalEqualizerHz(valueat60Hz, hertzTemp);
            end
        end

        % Value changed function: Equalizer_150Hz
        function Equalizer_150HzValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            valueat150Hz = app.Equalizer_150Hz.Value;
            hertzTemp = 150;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).UniversalEqualizerHz(valueat150Hz, hertzTemp);
            end
        end

        % Value changed function: Equalizer_250Hz
        function Equalizer_250HzValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            valueat250Hz = app.Equalizer_250Hz.Value;
            hertzTemp = 250;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).UniversalEqualizerHz(valueat250Hz, hertzTemp);
            end
        end

        % Value changed function: Equalizer_500Hz
        function Equalizer_500HzValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            valueat500Hz = app.Equalizer_500Hz.Value;
            hertzTemp = 500;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).UniversalEqualizerHz(valueat500Hz, hertzTemp);
            end
        end

        % Value changed function: Equalizer_800Hz
        function Equalizer_800HzValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            valueat800Hz = app.Equalizer_800Hz.Value;
            hertzTemp = 800;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).UniversalEqualizerHz(valueat800Hz, hertzTemp);
            end
        end

        % Value changed function: Equalizer_1000Hz
        function Equalizer_1000HzValueChanged(app, event)
            if (app.selected == 0)
                errordlg('No sample is selected. Please select a sample.', 'Selection Error');
            else
            valueat1000Hz = app.Equalizer_1000Hz.Value;
            hertzTemp = 1000;
            app.audiosamples(app.selected) = app.audiosamples(app.selected).UniversalEqualizerHz(valueat1000Hz, hertzTemp);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadandPlaySamplesPanel = uipanel(app.UIFigure);
            app.LoadandPlaySamplesPanel.Title = 'Load and Play Samples';
            app.GridLayout4 = uigridlayout(app.LoadandPlaySamplesPanel);
            app.UIAxes3 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes3, 'Time (sec)')
            ylabel(app.UIAxes3, 'Amplitude (Hz)')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'white')
            app.LoadButton = uibutton(app.GridLayout4, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.GridLayout4, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.GridLayout4, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RecordButton = uibutton(app.GridLayout4, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Sample1Button = uibutton(app.GridLayout4, 'state');
            app.Sample1Button.ValueChangedFcn = createCallbackFcn(app, @Sample1ButtonValueChanged, true);
            app.Sample1Button.Text = 'Sample 1';
            app.Sample2Button = uibutton(app.GridLayout4, 'state');
            app.Sample2Button.ValueChangedFcn = createCallbackFcn(app, @Sample2ButtonValueChanged, true);
            app.Sample2Button.Text = 'Sample 2';
            app.Sample3Button = uibutton(app.GridLayout4, 'state');
            app.Sample3Button.ValueChangedFcn = createCallbackFcn(app, @Sample3ButtonValueChanged, true);
            app.Sample3Button.Text = 'Sample 3';
            app.Sample4Button = uibutton(app.GridLayout4, 'state');
            app.Sample4Button.ValueChangedFcn = createCallbackFcn(app, @Sample4ButtonValueChanged, true);
            app.Sample4Button.Text = 'Sample 4';
            app.Sample5Button = uibutton(app.GridLayout4, 'state');
            app.Sample5Button.ValueChangedFcn = createCallbackFcn(app, @Sample5ButtonValueChanged, true);
            app.Sample5Button.Text = 'Sample 5';
            app.Sample6Button = uibutton(app.GridLayout4, 'state');
            app.Sample6Button.ValueChangedFcn = createCallbackFcn(app, @Sample6ButtonValueChanged, true);
            app.Sample6Button.Text = 'Sample 6';
            app.Sample7Button = uibutton(app.GridLayout4, 'state');
            app.Sample7Button.ValueChangedFcn = createCallbackFcn(app, @Sample7ButtonValueChanged, true);
            app.Sample7Button.Text = 'Sample 7';
            app.Sample8Button = uibutton(app.GridLayout4, 'state');
            app.Sample8Button.ValueChangedFcn = createCallbackFcn(app, @Sample8ButtonValueChanged, true);
            app.Sample8Button.Text = 'Sample 8';
            app.Sample9Button = uibutton(app.GridLayout4, 'state');
            app.Sample9Button.ValueChangedFcn = createCallbackFcn(app, @Sample9ButtonValueChanged, true);
            app.Sample9Button.Text = 'Sample 9';
            app.ONOFFLabel = uilabel(app.GridLayout4);
            app.ONOFFLabel.Text = 'ON/OFF';
            app.Lamp_2 = uilamp(app.GridLayout4);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.TextArea_3 = uitextarea(app.InstructionsTab);
            app.TextArea_3.Value = {'1. Select a sample space (1-9) [TOP LEFT]'; ''; '2. Load/Record sample [MIDDLE LEFT]'; ''; '3. Play sample [MIDDLE LEFT]'; ''; '4. <Make modifications>'; ''; '5. Export Sample [MIDDLE LEFT]'; ''; 'ADDITIONAL FEATURES:'; ''; '- Error log [MIDDLE BOTTOM]'; ''; '- Pure tones [TAB 2]'; ''; '- Beats [ TAB 3]'};
            app.EditField = uieditfield(app.InstructionsTab, 'text');
            app.EditField.Value = 'Instructions for General Use';
            app.TonesTab = uitab(app.TabGroup);
            app.TonesTab.Title = 'Tones';
            app.ToneGenerationPanel = uipanel(app.TonesTab);
            app.ToneGenerationPanel.Title = 'Tone Generation';
            app.GridLayout3 = uigridlayout(app.ToneGenerationPanel);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Amplitude (Hz)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 0.01];
            app.UIAxes2.YLim = [-1.1 1.1];
            app.UIAxes2.LineStyleOrderIndex = 10;
            app.WaveTypeDropDown = uidropdown(app.GridLayout3);
            app.WaveTypeDropDown.Items = {'Sine', 'SawTooth', 'Square Wave'};
            app.WaveTypeDropDown.Value = 'Sine';
            app.ToneHzSpinnerLabel = uilabel(app.GridLayout3);
            app.ToneHzSpinnerLabel.Text = 'Tone (Hz)';
            app.ToneHzSpinner = uispinner(app.GridLayout3);
            app.ToneHzSpinner.Value = 440;
            app.PlayButton_2 = uibutton(app.GridLayout3, 'state');
            app.PlayButton_2.ValueChangedFcn = createCallbackFcn(app, @PlayButton_2ValueChanged, true);
            app.PlayButton_2.Text = 'Play';
            app.ExporttoSelectedSampleButton = uibutton(app.TonesTab, 'push');
            app.ExporttoSelectedSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoSelectedSampleButtonPushed, true);
            app.ExporttoSelectedSampleButton.Text = 'Export to Selected Sample';
            app.BeatTab = uitab(app.TabGroup);
            app.BeatTab.Title = 'Beat';
            app.BeatPanel = uipanel(app.BeatTab);
            app.BeatPanel.Title = 'Beat';
            app.GridLayout = uigridlayout(app.BeatPanel);
            app.Button = uibutton(app.GridLayout, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '';
            app.Drum1Label = uilabel(app.GridLayout);
            app.Drum1Label.Text = 'Drum 1';
            app.PlayButton_3 = uibutton(app.GridLayout, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.Button_2 = uibutton(app.GridLayout, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '+';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '+';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '3';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '+';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '4';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '+';
            app.BPMSpinnerLabel = uilabel(app.GridLayout);
            app.BPMSpinnerLabel.Text = 'BPM';
            app.BPMSpinner = uispinner(app.GridLayout);
            app.BPMSpinner.Limits = [0 300];
            app.BPMSpinner.RoundFractionalValues = 'on';
            app.BPMSpinner.Value = 120;
            app.EffectsandModulationPanel = uipanel(app.UIFigure);
            app.EffectsandModulationPanel.Title = 'Effects and Modulation';
            app.PlaybackDirectionSwitch = uiswitch(app.EffectsandModulationPanel, 'toggle');
            app.PlaybackDirectionSwitch.Items = {'Backward', 'Forward'};
            app.PlaybackDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaybackDirectionSwitchValueChanged, true);
            app.PlaybackDirectionSwitch.Value = 'Forward';
            app.Equalizer_60Hz = uislider(app.EffectsandModulationPanel);
            app.Equalizer_60Hz.ValueChangedFcn = createCallbackFcn(app, @Equalizer_60HzValueChanged, true);
            app.Equalizer_150Hz = uislider(app.EffectsandModulationPanel);
            app.Equalizer_150Hz.ValueChangedFcn = createCallbackFcn(app, @Equalizer_150HzValueChanged, true);
            app.Equalizer_250Hz = uislider(app.EffectsandModulationPanel);
            app.Equalizer_250Hz.ValueChangedFcn = createCallbackFcn(app, @Equalizer_250HzValueChanged, true);
            app.Equalizer_500Hz = uislider(app.EffectsandModulationPanel);
            app.Equalizer_500Hz.ValueChangedFcn = createCallbackFcn(app, @Equalizer_500HzValueChanged, true);
            app.Equalizer_800Hz = uislider(app.EffectsandModulationPanel);
            app.Equalizer_800Hz.ValueChangedFcn = createCallbackFcn(app, @Equalizer_800HzValueChanged, true);
            app.Equalizer_1000Hz = uislider(app.EffectsandModulationPanel);
            app.Equalizer_1000Hz.ValueChangedFcn = createCallbackFcn(app, @Equalizer_1000HzValueChanged, true);
            app.HzLabel = uilabel(app.EffectsandModulationPanel);
            app.HzLabel.Text = '60 Hz';
            app.HzLabel_2 = uilabel(app.EffectsandModulationPanel);
            app.HzLabel_2.Text = '150 Hz';
            app.HzLabel_3 = uilabel(app.EffectsandModulationPanel);
            app.HzLabel_3.Text = '250 Hz';
            app.HzLabel_4 = uilabel(app.EffectsandModulationPanel);
            app.HzLabel_4.Text = '500 Hz';
            app.HzLabel_5 = uilabel(app.EffectsandModulationPanel);
            app.HzLabel_5.Text = '800Hz';
            app.kHzLabel_3 = uilabel(app.EffectsandModulationPanel);
            app.kHzLabel_3.Text = '1 kHz';
            app.EqualizerLabel = uilabel(app.EffectsandModulationPanel);
            app.EqualizerLabel.Text = 'Equalizer';
            app.FeedbackKnobLabel = uilabel(app.EffectsandModulationPanel);
            app.FeedbackKnobLabel.Text = 'Feedback';
            app.FeedbackKnob = uiknob(app.EffectsandModulationPanel, 'continuous');
            app.FeedbackKnob.Limits = [1 5];
            app.FeedbackKnob.ValueChangedFcn = createCallbackFcn(app, @FeedbackKnobValueChanged, true);
            app.FeedbackKnob.Value = 1;
            app.DelayKnobLabel = uilabel(app.EffectsandModulationPanel);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.EffectsandModulationPanel, 'continuous');
            app.DelayKnob.Limits = [0 2];
            app.DelayKnob.ValueChangedFcn = createCallbackFcn(app, @DelayKnobValueChanged, true);
            app.VolumeKnobLabel = uilabel(app.EffectsandModulationPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.EffectsandModulationPanel, 'continuous');
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.Value = 25;
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectsandModulationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectsandModulationPanel);
            app.PlaybackSpeedSlider.Limits = [0 5];
            app.PlaybackSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedSliderValueChanged, true);
            app.PlaybackSpeedSlider.Value = 1;
            app.ChoppingPanel = uipanel(app.UIFigure);
            app.ChoppingPanel.Title = 'Chopping';
            app.GridLayout2 = uigridlayout(app.ChoppingPanel);
            app.EndSliderLabel_2 = uilabel(app.GridLayout2);
            app.EndSliderLabel_2.Text = 'Start';
            app.EndSlider = uislider(app.GridLayout2);
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @EndSliderValueChanged, true);
            app.EndSlider.Value = 100;
            app.StartSlider = uislider(app.GridLayout2);
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @StartSliderValueChanged, true);
            app.EndSliderLabel_3 = uilabel(app.GridLayout2);
            app.EndSliderLabel_3.Text = 'End';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'MATMIX AUDIO SAMPLER'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'By Naia Dalal, Alexander Guess, and Sovie Prasad Shekhar'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MasterProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end